classdef GUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ModulationIndexEditField       matlab.ui.control.NumericEditField
        ModulationIndexEditFieldLabel  matlab.ui.control.Label
        MODULATIONSCHEMESUSINGMATLABAPPDESIGNERLabel  matlab.ui.control.Label
        VSBButton                      matlab.ui.control.Button
        SSBSCButton                    matlab.ui.control.Button
        DSBSCButton                    matlab.ui.control.Button
        FMButton                       matlab.ui.control.Button
        AMButton                       matlab.ui.control.Button
        CarrierLabel                   matlab.ui.control.Label
        MessageLabel                   matlab.ui.control.Label
        FcEditField                    matlab.ui.control.NumericEditField
        FcEditFieldLabel               matlab.ui.control.Label
        VcEditField                    matlab.ui.control.NumericEditField
        VcEditFieldLabel               matlab.ui.control.Label
        FmEditField                    matlab.ui.control.NumericEditField
        FmEditFieldLabel               matlab.ui.control.Label
        VmEditField                    matlab.ui.control.NumericEditField
        VmEditFieldLabel               matlab.ui.control.Label
        UIAxes4                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    %properties (Access = private)
     %   dir = 0;
      %  score =0;
    %end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function addButtonPushed(app, event)
           % n1=app.n1EditField.Value;
            %n2=app.n2EditField.Value;
            %y=n1+n2;
            %app.yEditField.Value=y;
        end

        % Callback function
        function subtractButtonPushed(app, event)
           % n1=app.n1EditField.Value;
            %n2=app.n2EditField.Value;
            %y=n1-n2;
            %app.yEditField.Value=y;
        end

        % Callback function
        function productButtonPushed(app, event)
          %  n1=app.n1EditField.Value;
          %  n2=app.n2EditField.Value;
           % y=n1*n2;
          %  app.yEditField.Value=y;
        end

        % Callback function
        function divisionButtonPushed(app, event)
           % n1=app.n1EditField.Value;
           % n2=app.n2EditField.Value;
           % y=n1/n2;
           % app.yEditField.Value=y;
        end

        % Callback function
        function fmEditFieldValueChanged(app, event)
           % Fm =  app.VmEditField.Value;
           % Vm = app.FmEditField.Value;
            %Ta = 1/Fm;
            %t = 0:Ta/999:6*Ta;
            %y = Vm*sin(2*pi*Fm*t);
            %plot(app.UIAxes,y,'r');
        end

        % Callback function
        function fcEditFieldValueChanged(app, event)
            %fm =  app.VmEditField.Value;
            %fc = app.VcEditField.Value;
            %vc = app.FcEditField.Value;
            %vm = app.FmEditField.Value;
            %Ta = 1/fm;
            %t = 0:Ta/999:6*Ta;
            %yc = vc*sin(2*pi*fc*t);
            %plot(app.UIAxes2,yc);
            %m = vm/vc;
            %y = vc*(1+m*sin(2*pi*fm*t)).*sin(2*pi*fc*t);
            %plot(app.UIAxes3,y,'g');
        end

        % Callback function
        function UIAxesButtonDown(app, event)
          %  y=sin(90);
        end

        % Callback function
        function RecordButtonPushed(app, event)
          %  audioObject=audiorecorder;
           % duration = app.DurationEditField.Value;
            %msgbox("Record Started");
            %recordblocking(audioObject,duration);
            %msgbox("Record Stopped");
            %assignin('base','audioObject',audioObject);
        end

        % Callback function
        function playButtonPushed(app, event)
            %audioObject=evalin('base','audioObject');
            %y=getaudiodata(audioObject);
            %Fs = audioObject.SampleRate;
            %multiplier = app.SpeedKnob.Value;
            %sound(y,fs*multiplier);
        end

        % Callback function
        function STARTButtonPushed(app, event)
            %dim = 16;
           % app.score = 0;
            %app.dir = 0;
            %cla(app.UIAxes)
            %v = 0.9;
            %f = [0 0];
           % f(1)= round(rand*dim);
            %f(2)= round(rand*dim);
           % s=zeros(100,2);
            %s(1,1)= round(dim/2);
           % s(1,2)= round(dim/2);
           % s(2,1) = 5;
            %s(2,2) = 6;
           % snake = plot(app.UIAxes,s([1 2],1),s([1 2],2), 'Marker',".", "MarkerSize", 20, 'LineStyle',"-", 'LineWidth', 10, 'Color', '#4CCB32')
           % hold (app.UIAxes, 'on')
           % food = plot(app.UIAxes, f(1,1), f(1,2), 'Marker',".", "MarkerSize", 30, 'MarkerFaceColor', 'r')
           % xlim(app.UIAxes, [0 20])
          %  ylim(app.UIAxes, [0 20])
           % while(1)
           %     id = 0;
           %     s_old = s;
                %Update head position
           %     if(app.dir==0)
             %       s(1,1)=s_old(1,1)-1;
             %       s(1,2)=s_old(1,2);
             %   elseif(app.dir==1)
              %      s(1,1)=s_old(1,1);
              %      s(1,2)=s_old(1,2)+1;
              %  elseif(app.dir==2)
              %      s(1,1)=s_old(1,1)+1;
              %      s(1,2)=s_old(1,2);
              %  elseif(app.dir==3)
               %     s(1,1)=s_old(1,1);
               %     s(1,2)=s_old(1,2)-1;
               % end
                %Update Body Position
               %for i=2:1:length(nonzeros(s(:,1)))
               %     s(i,1)=s_old(i-1,1);
                %    s(i,2)=s_old(i-1,2);
               % end
                %limit area
               % if(s(1,1)>dim||s(1,1)<0||s(1,2)>dim||s(1,2)<0)
               %    msgbox(strcat('Out of the limits, game ended! Score:',int2str(app.score)),'End')
                %    break
               % end
                %touch body
              %  if(isempty(find(prod(s(1,1:2)==s(2:end,1:2),2)))==0)
              %      msgbox(strcat('Out of the limits, game ended! Score:',int2str(app.score)),'End')
              %      break
              %  end
                %Eating
              %  if (s(1, [1 2])==f)
              %      s(lenght (nonzeros(s(:,1)))+1,1) = s_old(lenght(nonzeros(s_old(:,2))),1);
               %     s (lenght (nonzeros(s(:,1)))+1,1) = s_old(lenght (nonzeros(s_old(:,2))),1);
               %     f(1)= round(rand*dim);
               %     f(2)= round(rand*dim);
               %     app.score = app.score + 1;
                %    v = v-0.02;
               % end
                %Plotting
               % app.SCOREEditField.Value = int2str(app.score);
               % set (snake, 'XData', nonzeros(s(:,1)), 'YData', nonzeros(s(:,2)));
              % set(food, 'XData', nonzeros(f(:,1)), 'YData', nonzeros(f(:,2)));
              %  pause(v)

            %end 
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
           % key = event.Key;
           % if (strcmp(key, 'leftarrow') && app.dir~=2) 
            %    spp.dir = 0;
            %end
           % if(strcmp(key, 'rightarrow') && app.dir~=0) 
            %    spp.dir = 2;
           % end
           % if (strcmp(key, 'uparrow') && app.dir~=3) 
           %     spp.dir = 1;
           % end
            %if(strcmp(key, 'downarrow') && app.dir~=1) 
          %      spp.dir = 3;
           % end
        end

        % Callback function
        function PWMButtonPushed(app, event)
           % t=1:0.1:10;
            %a=sin(t);
            %plot(app.UIAxes,t,a);
            %PWM signal below : converting the values of a to 0 and 1
            %b=a;
            %b=b.*(b>0);
            %b=b./(b+1.*(b==0));
            %plot(app.UIAxes2,t,b);
        end

        % Button pushed function: AMButton
        function AMButtonPushed(app, event)
            fc = app.FcEditField.Value;
            vm = app.VmEditField.Value;
            fm = app.FmEditField.Value;
            vc = app.VcEditField.Value;

            % Time vector
            t = 0:0.001:1; % Adjust time vector as needed

            message_signal = vm*sin(2*pi*fm*t);
            carrier_signal = vc * sin(2*pi*fc*t);

            % Calculate AM modulation signal
            m = vm / vc; % Modulation index
            app.ModulationIndexEditField.Value = m; % Display modulation index in the edit field
            modulated_signal = vc * (1 + m * sin(2*pi*fm*t)) .* sin(2*pi*fc*t);

          
            % Plot on UIAxes, UIAxes2, UIAxes3
            plot(app.UIAxes, t, message_signal);
            title(app.UIAxes, 'Carrier Signal');
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Amplitude');

            plot(app.UIAxes2, t, carrier_signal);
            title(app.UIAxes2, 'Message Signal');
            xlabel(app.UIAxes2, 'Time');
            ylabel(app.UIAxes2, 'Amplitude');

            plot(app.UIAxes3, t, modulated_signal);
            title(app.UIAxes3, 'AM Modulated Signal');
            xlabel(app.UIAxes3, 'Time');
            ylabel(app.UIAxes3, 'Amplitude');

            plot(app.UIAxes4, t, fft(modulated_signal));
            title(app.UIAxes4, 'AM In Spectral Domain');
            xlabel(app.UIAxes4, 'Time');
            ylabel(app.UIAxes4, 'Amplitude');
        end

        % Button pushed function: FMButton
        function FMButtonPushed(app, event)
            % Assuming you have the following values from your UI or elsewhere
            fc = app.FcEditField.Value; % Carrier frequency
            vc = app.VcEditField.Value; % Carrier amplitude
            fm = app.FmEditField.Value; % Modulating frequency
            vm = app.VmEditField.Value; % Modulating amplitude

            % Time vector
            t = 0:0.001:1; % Adjust time vector as needed

            % Calculate FM modulation signal
            % Modulation index beta
            beta = 1; % FM modulation index
            app.ModulationIndexEditField.Value = beta; % Display modulation index in the edit field

            % Carrier signal
            carrier_signal = vc * sin(2*pi*fc*t);

            % Modulated signal (FM modulation)
            modulated_signal = vc * sin(2*pi*fc*t + beta * sin(2*pi*fm*t));

            % Plot on UIAxes, UIAxes2, UIAxes3
            plot(app.UIAxes, t, vm * sin(2*pi*fm*t));
            title(app.UIAxes, 'Carrier Signal');
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Amplitude');

            plot(app.UIAxes2, t, carrier_signal);
            title(app.UIAxes2, 'Message Signal');
            xlabel(app.UIAxes2, 'Time');
            ylabel(app.UIAxes2, 'Amplitude');

            plot(app.UIAxes3, t, modulated_signal);
            title(app.UIAxes3, 'FM Modulated Signal');
            xlabel(app.UIAxes3, 'Time');
            ylabel(app.UIAxes3, 'Amplitude');

            plot(app.UIAxes4, t, fft(modulated_signal));
            title(app.UIAxes4, 'FM In Spectral Domain');
            xlabel(app.UIAxes4, 'Time');
            ylabel(app.UIAxes4, 'Amplitude');
        end

        % Button pushed function: DSBSCButton
        function DSBSCButtonPushed(app, event)
            fc = app.FcEditField.Value;
            vm = app.VmEditField.Value;
            fm = app.FmEditField.Value;
            vc = app.VcEditField.Value;

            % Time vector
            t = 0:0.001:1; % Adjust time vector as needed

            % Calculate DSB-SC modulation signal
            message_signal = vm * sin(2*pi*fm*t);
            carrier_signal = vc * sin(2*pi*fc*t);
            modulated_signal = message_signal.*carrier_signal;

             % Calculate Modulation Index
             mod_index = vm / vc;
             app.ModulationIndexEditField.Value = mod_index; % Display modulation index in the edit field


            % Plot on UIAxes, UIAxes2, UIAxes3
            plot(app.UIAxes, t, message_signal);
            title(app.UIAxes, 'Carrier Signal');
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Amplitude');

            plot(app.UIAxes2, t, carrier_signal);
            title(app.UIAxes2, 'Message Signal');
            xlabel(app.UIAxes2, 'Time');
            ylabel(app.UIAxes2, 'Amplitude');

            plot(app.UIAxes3, t, modulated_signal);
            title(app.UIAxes3, 'DSB-SC Modulated Signal');
            xlabel(app.UIAxes3, 'Time');
            ylabel(app.UIAxes3, 'Amplitude');

            plot(app.UIAxes4, t, fft(modulated_signal));
            title(app.UIAxes4, 'DSB-SC In Spectral Domain');
            xlabel(app.UIAxes4, 'Time');
            ylabel(app.UIAxes4, 'Amplitude');
        end

        % Button pushed function: SSBSCButton
        function SSBSCButtonPushed(app, event)
            fc = app.FcEditField.Value;
            vm = app.VmEditField.Value;
            fm = app.FmEditField.Value;
            vc = app.VcEditField.Value;
            v=(vm*vc)/2;

            % Time vector
            t = 0:0.001:1; % Adjust time vector as needed

            % Calculate SSB modulation signal
            message_signal = vm * cos(2*pi*fm*t);
            carrier_signal = vc * cos(2*pi*fc*t);
            modulated_signal =v.*((cos((2*pi)*fm*t)).*(cos(2*pi*fc*t))-(sin(2*pi*fm*t).*sin(2*pi*fc*t)));

            % Calculate Modulation Index
            modulation_index = vm / vc;
            app.ModulationIndexEditField.Value = modulation_index; % Display modulation index in edit field

            % Plot on UIAxes, UIAxes2, UIAxes3
            plot(app.UIAxes, t, message_signal);
            title(app.UIAxes, 'Carrier Signal');
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Amplitude');

            plot(app.UIAxes2, t, carrier_signal);
            title(app.UIAxes2, 'Message Signal');
            xlabel(app.UIAxes2, 'Time');
            ylabel(app.UIAxes2, 'Amplitude');

            plot(app.UIAxes3, t, modulated_signal);
            title(app.UIAxes3, 'SSB Modulated Signal');
            xlabel(app.UIAxes3, 'Time');
            ylabel(app.UIAxes3, 'Amplitude');

            plot(app.UIAxes4, t, fft(modulated_signal));
            title(app.UIAxes4, 'SSB-SC In Spectral Domain');
            xlabel(app.UIAxes4, 'Time');
            ylabel(app.UIAxes4, 'Amplitude');
        end

        % Button pushed function: VSBButton
        function VSBButtonPushed(app, event)
            % Parameters
            Fs = 1000;  % Sampling frequency (Hz)
            Fc = app.FcEditField.Value;   % Carrier frequency (Hz), fetched from GUI
            Fm = app.FmEditField.Value;   % Modulation frequency (Hz), fetched from GUI
            vm = app.VmEditField.Value;
            vc = app.VcEditField.Value;

            % Time vector
            t = 0:1/Fs:1;  % Adjust time vector as needed

            % Generate message signal (baseband)
            message_signal = cos(2*pi*10*t) + cos(2*pi*20*t);

            % Generate carrier signal
            carrier_signal = cos(2*pi*Fc*t);

            % SSB Modulation
            S = message_signal .* carrier_signal;  % Amplitude modulation (AM)
            S_vsb = ssbmod(message_signal, Fc, Fs, 0);  % Single Sideband modulation

            % Adding Vestigial Sideband (VSB)
            vestigial = 0.5 * S;  % Example of adding a vestigial component
            S_vsb_vestigial = S_vsb + vestigial;

            % Calculate Modulation Index
            m = vm / vc;  % Modulation index for VSB

            % Display the Modulation Index in a Numeric Edit Field
            app.ModulationIndexEditField.Value = m;

            % Plot on UIAxes, UIAxes2, UIAxes3
            plot(app.UIAxes, t, message_signal);
            title(app.UIAxes, 'Carrier Signal');
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Amplitude');

            plot(app.UIAxes2, t, carrier_signal);
            title(app.UIAxes2, 'Message Signal');
            xlabel(app.UIAxes2, 'Time');
            ylabel(app.UIAxes2, 'Amplitude');

            plot(app.UIAxes3, t, S_vsb_vestigial);
            title(app.UIAxes3, 'VSB Modulated Signal');
            xlabel(app.UIAxes3, 'Time');
            ylabel(app.UIAxes3, 'Amplitude');

            plot(app.UIAxes4, t, fft(S_vsb_vestigial));
            title(app.UIAxes4, 'VSB Modulated Signal');
            xlabel(app.UIAxes4, 'Time');
            ylabel(app.UIAxes4, 'Amplitude');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'white');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Carrier Signal')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            colormap(app.UIAxes, 'white')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Message Signal')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            colormap(app.UIAxes2, 'white')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Modulated Signal')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            colormap(app.UIAxes3, 'white')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Spectral Domain')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.VmEditFieldLabel = uilabel(app.UIFigure);
            app.VmEditFieldLabel.Text = 'Vm';
            app.VmEditField = uieditfield(app.UIFigure, 'numeric');
            app.FmEditFieldLabel = uilabel(app.UIFigure);
            app.FmEditFieldLabel.Text = 'Fm';
            app.FmEditField = uieditfield(app.UIFigure, 'numeric');
            app.VcEditFieldLabel = uilabel(app.UIFigure);
            app.VcEditFieldLabel.Text = 'Vc';
            app.VcEditField = uieditfield(app.UIFigure, 'numeric');
            app.FcEditFieldLabel = uilabel(app.UIFigure);
            app.FcEditFieldLabel.Text = 'Fc';
            app.FcEditField = uieditfield(app.UIFigure, 'numeric');
            app.MessageLabel = uilabel(app.UIFigure);
            app.MessageLabel.Text = 'Message';
            app.CarrierLabel = uilabel(app.UIFigure);
            app.CarrierLabel.Text = 'Carrier';
            app.AMButton = uibutton(app.UIFigure, 'push');
            app.AMButton.ButtonPushedFcn = createCallbackFcn(app, @AMButtonPushed, true);
            app.AMButton.Text = 'AM';
            app.FMButton = uibutton(app.UIFigure, 'push');
            app.FMButton.ButtonPushedFcn = createCallbackFcn(app, @FMButtonPushed, true);
            app.FMButton.Text = 'FM';
            app.DSBSCButton = uibutton(app.UIFigure, 'push');
            app.DSBSCButton.ButtonPushedFcn = createCallbackFcn(app, @DSBSCButtonPushed, true);
            app.DSBSCButton.Text = 'DSB-SC';
            app.SSBSCButton = uibutton(app.UIFigure, 'push');
            app.SSBSCButton.ButtonPushedFcn = createCallbackFcn(app, @SSBSCButtonPushed, true);
            app.SSBSCButton.Text = 'SSB-SC';
            app.VSBButton = uibutton(app.UIFigure, 'push');
            app.VSBButton.ButtonPushedFcn = createCallbackFcn(app, @VSBButtonPushed, true);
            app.VSBButton.Text = 'VSB';
            app.MODULATIONSCHEMESUSINGMATLABAPPDESIGNERLabel = uilabel(app.UIFigure);
            app.MODULATIONSCHEMESUSINGMATLABAPPDESIGNERLabel.Text = 'MODULATION SCHEMES USING MATLAB APP DESIGNER';
            app.ModulationIndexEditFieldLabel = uilabel(app.UIFigure);
            app.ModulationIndexEditFieldLabel.Text = 'Modulation Index';
            app.ModulationIndexEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end